classdef mazegenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridSizeSpinner           matlab.ui.control.Spinner
        GridSizeSpinnerLabel      matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        DisplaysolutionCheckBox   matlab.ui.control.CheckBox
        BranchingModeButtonGroup  matlab.ui.container.ButtonGroup
        lastButton                matlab.ui.control.ToggleButton
        middleButton              matlab.ui.control.ToggleButton
        firstButton               matlab.ui.control.ToggleButton
        NewMazeButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        slen % Length of the maze's solution
        solplot % Handle to solution plot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BranchingModeButtonGroup, GridSizeSpinner, 
        % ...and 1 other component
        function NewMaze(app, event)
            gridsize = app.GridSizeSpinner.Value;
            bmode = app.BranchingModeButtonGroup.SelectedObject.Text;
            
            [wall,wx,wy,sol,sx,sy] = amaze(gridsize,bmode);
            app.slen = numedges(sol);
            plot(app.UIAxes,wall,"XData",wx,"YData",wy,"NodeLabel",[])
            axis(app.UIAxes,"equal")
            
            hold(app.UIAxes,"on")
            app.solplot = plot(app.UIAxes,sol,"XData",sx,"YData",sy,"NodeLabel",[]);
            hold(app.UIAxes,"off")      
            
            app.solplot.Visible = false;

            app.DisplaysolutionCheckBox.Enable = true;
            DisplaysolutionCheckBoxValueChanged(app,[])
        end

        % Value changed function: DisplaysolutionCheckBox
        function DisplaysolutionCheckBoxValueChanged(app, event)
            value = app.DisplaysolutionCheckBox.Value;

            if value
                app.solplot.Visible = true;
                title(app.UIAxes,"length = " + app.slen)
            else
                app.solplot.Visible = false;
                title(app.UIAxes,"Maze")
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes)
            title(app.UIAxes,"Maze")
            app.DisplaysolutionCheckBox.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Maze')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.NewMazeButton = uibutton(app.GridLayout, 'push');
            app.NewMazeButton.ButtonPushedFcn = createCallbackFcn(app, @NewMaze, true);
            app.NewMazeButton.Text = 'New Maze';
            app.BranchingModeButtonGroup = uibuttongroup(app.GridLayout);
            app.BranchingModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NewMaze, true);
            app.BranchingModeButtonGroup.Title = 'Branching Mode';
            app.firstButton = uitogglebutton(app.BranchingModeButtonGroup);
            app.firstButton.Text = 'first';
            app.firstButton.Value = true;
            app.middleButton = uitogglebutton(app.BranchingModeButtonGroup);
            app.middleButton.Text = 'middle';
            app.lastButton = uitogglebutton(app.BranchingModeButtonGroup);
            app.lastButton.Text = 'last';
            app.DisplaysolutionCheckBox = uicheckbox(app.GridLayout);
            app.DisplaysolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplaysolutionCheckBoxValueChanged, true);
            app.DisplaysolutionCheckBox.Enable = 'off';
            app.DisplaysolutionCheckBox.Text = 'Display solution?';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GridSizeSpinnerLabel = uilabel(app.GridLayout);
            app.GridSizeSpinnerLabel.Text = 'Grid Size';
            app.GridSizeSpinner = uispinner(app.GridLayout);
            app.GridSizeSpinner.Step = 5;
            app.GridSizeSpinner.Limits = [5 30];
            app.GridSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @NewMaze, true);
            app.GridSizeSpinner.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mazegenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end